classdef AWGapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        configTab                      matlab.ui.container.Tab
        PingTestPanel                  matlab.ui.container.Panel
        PingServerButton               matlab.ui.control.Button
        PingTestLamp                   matlab.ui.control.Lamp
        PingTestErrorLabel             matlab.ui.control.Label
        PingAWGPanel                   matlab.ui.container.Panel
        PingAWGButton                  matlab.ui.control.Button
        PingAWGLamp                    matlab.ui.control.Lamp
        PingAWGErrorLabel              matlab.ui.control.Label
        errorLogTab                    matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        AutoRefreshCheckBox            matlab.ui.control.CheckBox
        EditField                      matlab.ui.control.EditField
        SendButton                     matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        uploadtraceTab                 matlab.ui.container.Tab
        LocalFilesPanel                matlab.ui.container.Panel
        TextArea_2                     matlab.ui.control.TextArea
        UploadingfiletxtLabel          matlab.ui.control.Label
        SelectfilesButton              matlab.ui.control.Button
        RemoteFilesPanel               matlab.ui.container.Panel
        Tree                           matlab.ui.container.Tree
        Node                           matlab.ui.container.TreeNode
        Node2                          matlab.ui.container.TreeNode
        SendtoAWGButton                matlab.ui.control.StateButton
        TextArea_3                     matlab.ui.control.TextArea
        ChannelSelectionDropDownLabel  matlab.ui.control.Label
        ChannelSelectionDropDown       matlab.ui.control.DropDown
        outputcontrolsTab              matlab.ui.container.Tab
        SourcefrequencyEditFieldLabel  matlab.ui.control.Label
        SourcefrequencyEditField       matlab.ui.control.EditField
        Channel1CheckBox               matlab.ui.control.CheckBox
        Channel2CheckBox               matlab.ui.control.CheckBox
        Channel3CheckBox               matlab.ui.control.CheckBox
        Channel4CheckBox               matlab.ui.control.CheckBox
        startButton                    matlab.ui.control.StateButton
        stopButton                     matlab.ui.control.StateButton
        rastfreqEditFieldLabel         matlab.ui.control.Label
        rastfreqEditField              matlab.ui.control.EditField
        sequenceloadTab                matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        SaveButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            PingServerButtonPushed(app)
        end

        % Button pushed function: PingServerButton
        function PingServerButtonPushed(app, event)
        app.PingTestErrorLabel.Text = "pinging";
        app.PingTestErrorLabel.FontColor=[0.93,0.69,0.13];
        app.PingTestLamp.Color = [0.93,0.69,0.13];
        drawnow
        
        [status, commandOut] = system('python pingTest.py');
            if status==2
                uialert(app.UIFigure,"python files not found add them using...","startup error")
            end
            if status==0
                app.PingTestLamp.Color=[0.0,1.0,0.0];
                app.PingTestErrorLabel.FontColor=[0.0,1.0,0.0];
            else
                app.PingTestLamp.Color=[1.0,0.0,0.0];
                app.PingTestErrorLabel.FontColor=[1.0,0.0,0.0];
            end
            app.PingTestErrorLabel.Text = commandOut;
        end

        % Button pushed function: PingAWGButton
        function PingAWGButtonPushed(app, event)
            app.PingAWGErrorLabel.Text = "pinging";
            app.PingAWGErrorLabel.FontColor=[0.93,0.69,0.13];
            app.PingAWGLamp.Color = [0.93,0.69,0.13];
            drawnow
            
            [status, commandOut] = system('python query.py TCPIP0::localhost::inst1::INSTR *IDN?');
            
            if status==0
                app.PingAWGLamp.Color=[0.0,1.0,0.0];
                app.PingAWGErrorLabel.FontColor=[0.0,1.0,0.0];
            else
                app.PingAWGLamp.Color=[1.0,0.0,0.0];
                app.PingAWGErrorLabel.FontColor=[1.0,0.0,0.0];
            end
            
            app.PingAWGErrorLabel.Text = commandOut;
        end

        % Button pushed function: SelectfilesButton
        function SelectfilesButtonPushed(app, event)
            [file,path] = uigetfile('.txt','Select File(s)','MultiSelect', 'on')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.configTab = uitab(app.TabGroup);
            app.configTab.Title = 'config';
            app.PingTestPanel = uipanel(app.configTab);
            app.PingServerButton = uibutton(app.PingTestPanel, 'push');
            app.PingServerButton.ButtonPushedFcn = createCallbackFcn(app, @PingServerButtonPushed, true);
            app.PingServerButton.Text = 'Ping Server';
            app.PingTestLamp = uilamp(app.PingTestPanel);
            app.PingTestErrorLabel = uilabel(app.PingTestPanel);
            app.PingTestErrorLabel.Text = {''; ''};
            app.PingAWGPanel = uipanel(app.configTab);
            app.PingAWGButton = uibutton(app.PingAWGPanel, 'push');
            app.PingAWGButton.ButtonPushedFcn = createCallbackFcn(app, @PingAWGButtonPushed, true);
            app.PingAWGButton.Text = 'Ping AWG';
            app.PingAWGLamp = uilamp(app.PingAWGPanel);
            app.PingAWGErrorLabel = uilabel(app.PingAWGPanel);
            app.PingAWGErrorLabel.Text = '';
            app.errorLogTab = uitab(app.TabGroup);
            app.errorLogTab.Title = 'errorLog';
            app.TextArea = uitextarea(app.errorLogTab);
            app.AutoRefreshCheckBox = uicheckbox(app.errorLogTab);
            app.AutoRefreshCheckBox.Text = 'Auto Refresh';
            app.EditField = uieditfield(app.errorLogTab, 'text');
            app.EditField.Value = 'Manual prompt';
            app.SendButton = uibutton(app.errorLogTab, 'push');
            app.SendButton.Text = 'Send';
            app.RefreshButton = uibutton(app.errorLogTab, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.uploadtraceTab = uitab(app.TabGroup);
            app.uploadtraceTab.Title = 'upload trace';
            app.LocalFilesPanel = uipanel(app.uploadtraceTab);
            app.LocalFilesPanel.Title = 'Local Files';
            app.TextArea_2 = uitextarea(app.LocalFilesPanel);
            app.TextArea_2.Value = {'Error console'};
            app.UploadingfiletxtLabel = uilabel(app.LocalFilesPanel);
            app.UploadingfiletxtLabel.Text = 'Uploading file.txt...';
            app.SelectfilesButton = uibutton(app.LocalFilesPanel, 'push');
            app.SelectfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfilesButtonPushed, true);
            app.SelectfilesButton.Text = 'Select file(s)';
            app.RemoteFilesPanel = uipanel(app.uploadtraceTab);
            app.RemoteFilesPanel.Title = 'Remote Files';
            app.Tree = uitree(app.RemoteFilesPanel);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Tree);
            app.Node2.Text = 'Node2';
            app.SendtoAWGButton = uibutton(app.RemoteFilesPanel, 'state');
            app.SendtoAWGButton.Text = 'Send to AWG';
            app.TextArea_3 = uitextarea(app.RemoteFilesPanel);
            app.TextArea_3.Value = {'AWG Error console'; ''};
            app.ChannelSelectionDropDownLabel = uilabel(app.RemoteFilesPanel);
            app.ChannelSelectionDropDownLabel.Text = 'Channel Selection';
            app.ChannelSelectionDropDown = uidropdown(app.RemoteFilesPanel);
            app.outputcontrolsTab = uitab(app.TabGroup);
            app.outputcontrolsTab.Title = 'output controls';
            app.SourcefrequencyEditFieldLabel = uilabel(app.outputcontrolsTab);
            app.SourcefrequencyEditFieldLabel.Text = 'Source frequency';
            app.SourcefrequencyEditField = uieditfield(app.outputcontrolsTab, 'text');
            app.Channel1CheckBox = uicheckbox(app.outputcontrolsTab);
            app.Channel1CheckBox.Text = 'Channel 1';
            app.Channel2CheckBox = uicheckbox(app.outputcontrolsTab);
            app.Channel2CheckBox.Text = 'Channel 2';
            app.Channel3CheckBox = uicheckbox(app.outputcontrolsTab);
            app.Channel3CheckBox.Text = 'Channel 3';
            app.Channel4CheckBox = uicheckbox(app.outputcontrolsTab);
            app.Channel4CheckBox.Text = 'Channel 4';
            app.startButton = uibutton(app.outputcontrolsTab, 'state');
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.outputcontrolsTab, 'state');
            app.stopButton.Text = 'stop';
            app.rastfreqEditFieldLabel = uilabel(app.outputcontrolsTab);
            app.rastfreqEditFieldLabel.Text = 'rast freq???';
            app.rastfreqEditField = uieditfield(app.outputcontrolsTab, 'text');
            app.sequenceloadTab = uitab(app.TabGroup);
            app.sequenceloadTab.Title = 'sequence load';
            app.UITable = uitable(app.sequenceloadTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveButton = uibutton(app.sequenceloadTab, 'push');
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.sequenceloadTab, 'push');
            app.LoadButton.Text = 'Load';
            app.StartButton = uibutton(app.sequenceloadTab, 'push');
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWGapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end